classdef conv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Button            matlab.ui.control.Button
        NN30Label         matlab.ui.control.Label
        N3EditField       matlab.ui.control.NumericEditField
        N3EditFieldLabel  matlab.ui.control.Label
        N2EditField       matlab.ui.control.NumericEditField
        N2EditFieldLabel  matlab.ui.control.Label
        N1EditField       matlab.ui.control.NumericEditField
        N1EditFieldLabel  matlab.ui.control.Label
        ButtonGroup       matlab.ui.container.ButtonGroup
        CH2Button         matlab.ui.control.RadioButton
        CH1Button         matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        CallingApp % Description
        N1
        N2
        N3
        channelll
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.CallingApp=main;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %将值赋给临时变量
            if app.CH1Button.Value==1
                app.channelll=1;
            elseif app.CH2Button==1
                app.channelll=2;
            end
            app.N1=app.N1EditField.Value;
            app.N2=app.N2EditField.Value;
            app.N3=app.N3EditField.Value;
            %将值传递给主程序变量
            app.CallingApp.N1=app.N1;
            app.CallingApp.N2=app.N2;
            app.CallingApp.N3=app.N3;
            app.CallingApp.channelll=app.channelll;
            %打开按钮
            app.CallingApp.Menu_8.Enable="on";
            %删除此页面
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '卷积对象';
            app.CH1Button = uiradiobutton(app.ButtonGroup);
            app.CH1Button.Text = 'CH1';
            app.CH1Button.Value = true;
            app.CH2Button = uiradiobutton(app.ButtonGroup);
            app.CH2Button.Text = 'CH2';
            app.N1EditFieldLabel = uilabel(app.UIFigure);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.UIFigure, 'numeric');
            app.N1EditField.Value = 0.5;
            app.N2EditFieldLabel = uilabel(app.UIFigure);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.UIFigure, 'numeric');
            app.N2EditField.Value = 0.5;
            app.N3EditFieldLabel = uilabel(app.UIFigure);
            app.N3EditFieldLabel.Text = 'N3';
            app.N3EditField = uieditfield(app.UIFigure, 'numeric');
            app.NN30Label = uilabel(app.UIFigure);
            app.NN30Label.Text = '卷积核N（N3为0则为无）';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = conv(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end