classdef filter_designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label_2                matlab.ui.control.Label
        filterdesignerButton   matlab.ui.control.Button
        filterdesignerLabel_2  matlab.ui.control.Label
        filterdesignerLabel    matlab.ui.control.Label
        Button                 matlab.ui.control.Button
        ButtonGroup            matlab.ui.container.ButtonGroup
        CH2Button              matlab.ui.control.RadioButton
        CH1Button              matlab.ui.control.RadioButton
        EditField              matlab.ui.control.EditField
        Label                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        fdchannel
        fcnname
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.CallingApp=main;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %判断频道
            if app.CH1Button.Value==1
                app.fdchannel=1;
            elseif app.CH2Button.Value==1
                app.fdchannel=2;
            end
            % 获取用户输入的函数名称
            app.fcnname = app.EditField.Value;  % 假设用户输入的是函数名称
            
            % 在当前文件夹中检查函数文件是否存在
            if exist([app.fcnname '.m'], 'file') == 2  % 2 表示 MATLAB 文件 (.m) 存在
                % 如果存在，执行相应操作
                app.Label_2.Text = ['函数 ' app.fcnname ' 已找到'];
                %将临时变量的值赋给主页面
                app.CallingApp.fdchannel=app.fdchannel;
                app.CallingApp.fcnname=app.fcnname;
                %打开按钮
                app.CallingApp.FilterDesignerMenu.Enable="on";
                %调用主页面中进行滤波的函数
                app.CallingApp.start_filter_designer();
                %关闭此页面
                delete(app);
            else
                % 如果不存在，提示用户函数未找到
                app.Label_2.Text = ['函数 ' app.fcnname ' 不存在'];
            end
            
        end

        % Button pushed function: filterdesignerButton
        function filterdesignerButtonPushed(app, event)
            filterDesigner;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '滤波器函数名';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '频道';
            app.CH1Button = uiradiobutton(app.ButtonGroup);
            app.CH1Button.Text = 'CH1';
            app.CH1Button.Value = true;
            app.CH2Button = uiradiobutton(app.ButtonGroup);
            app.CH2Button.Text = 'CH2';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            app.filterdesignerLabel = uilabel(app.UIFigure);
            app.filterdesignerLabel.Text = {'使用filter designer设计的滤波器'; '对频道信号进行滤波处理'};
            app.filterdesignerLabel_2 = uilabel(app.UIFigure);
            app.filterdesignerLabel_2.Text = {'如果你还没有设计好滤波器'; '可以点击按钮打开filter designer'; '将设计好的滤波器保存在同级文件夹'};
            app.filterdesignerButton = uibutton(app.UIFigure, 'push');
            app.filterdesignerButton.ButtonPushedFcn = createCallbackFcn(app, @filterdesignerButtonPushed, true);
            app.filterdesignerButton.Text = 'filter designer';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '在这里输入你的滤波器函数名';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filter_designer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end