classdef generate_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label          matlab.ui.control.Label
        EditField_2    matlab.ui.control.NumericEditField
        Button_4       matlab.ui.control.Button
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        chripButton    matlab.ui.control.RadioButton
        Button_3       matlab.ui.control.RadioButton
        Button_2       matlab.ui.control.RadioButton
        Button         matlab.ui.control.RadioButton
        ButtonGroup    matlab.ui.container.ButtonGroup
        CH2Button      matlab.ui.control.RadioButton
        CH1Button      matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        CallingApp % Description
        signal_type=1 % Description
        channel=1
        T
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.CallingApp=main;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %传递信号类型的选项
            if app.Button.Value==1
                app.signal_type=1;
            elseif app.Button_2.Value==1
                app.signal_type=2;
            elseif app.Button_3.Value==1
                app.signal_type=3;
            elseif app.chripButton.Value==1
                app.signal_type=4;
            end
            %传递channel的选项
            if app.CH1Button.Value==1
                app.channel=1;
            elseif app.CH2Button.Value==1
                app.channel=2;
            end
            %将编辑文本传给T作为周期
            app.T=app.EditField_2.Value;
            %将值传递给main的参数
            app.CallingApp.signal_type=app.signal_type;
            app.CallingApp.channel=app.channel;
            app.CallingApp.T=app.T;
            %打开按钮
            app.CallingApp.Menu_5.Enable="on";
            %调用产生信号的函数
            app.CallingApp.start_generate_signal();
            %删除此页面
            delete(app);
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if selectedButton==app.chripButton || selectedButton==app.Button_3
                app.EditField_2.Visible="off";
                app.Label.Visible="off";
            else
                app.EditField_2.Visible="on";
                app.Label.Visible="on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '频道';
            app.CH1Button = uiradiobutton(app.ButtonGroup);
            app.CH1Button.Text = 'CH1';
            app.CH1Button.Value = true;
            app.CH2Button = uiradiobutton(app.ButtonGroup);
            app.CH2Button.Text = 'CH2';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '信号类型';
            app.Button = uiradiobutton(app.ButtonGroup_2);
            app.Button.Text = '方波';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_2.Text = '正弦波';
            app.Button_3 = uiradiobutton(app.ButtonGroup_2);
            app.Button_3.Text = '阶跃信号';
            app.chripButton = uiradiobutton(app.ButtonGroup_2);
            app.chripButton.Text = 'chrip信号';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '确定';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 2;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '周期';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generate_signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end