classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        Menu_3              matlab.ui.container.Menu
        CH1Menu             matlab.ui.container.Menu
        CH2Menu             matlab.ui.container.Menu
        Menu_4              matlab.ui.container.Menu
        Menu_5              matlab.ui.container.Menu
        Menu_6              matlab.ui.container.Menu
        Menu_25             matlab.ui.container.Menu
        CH1Menu_14          matlab.ui.container.Menu
        CH2Menu_14          matlab.ui.container.Menu
        Menu_26             matlab.ui.container.Menu
        CH1Menu_15          matlab.ui.container.Menu
        CH2Menu_15          matlab.ui.container.Menu
        Menu_2              matlab.ui.container.Menu
        Menu_7              matlab.ui.container.Menu
        Menu_9              matlab.ui.container.Menu
        CH1Menu_4           matlab.ui.container.Menu
        CH2Menu_4           matlab.ui.container.Menu
        MATLABMenu          matlab.ui.container.Menu
        CH1Menu_5           matlab.ui.container.Menu
        CH2Menu_5           matlab.ui.container.Menu
        Menu_8              matlab.ui.container.Menu
        Menu_12             matlab.ui.container.Menu
        Menu_13             matlab.ui.container.Menu
        convCH1CH2Menu      matlab.ui.container.Menu
        convCH2CH1Menu      matlab.ui.container.Menu
        Menu_14             matlab.ui.container.Menu
        Menu_22             matlab.ui.container.Menu
        CH1Menu_12          matlab.ui.container.Menu
        CH2Menu_12          matlab.ui.container.Menu
        Menu_23             matlab.ui.container.Menu
        xcorrCH1CH2Menu     matlab.ui.container.Menu
        xcorrCH2CH1Menu     matlab.ui.container.Menu
        Menu_10             matlab.ui.container.Menu
        Menu_11             matlab.ui.container.Menu
        FilterDesignerMenu  matlab.ui.container.Menu
        Menu_15             matlab.ui.container.Menu
        Menu_16             matlab.ui.container.Menu
        CH1Menu_7           matlab.ui.container.Menu
        CH2Menu_7           matlab.ui.container.Menu
        Menu_17             matlab.ui.container.Menu
        CH1Menu_8           matlab.ui.container.Menu
        CH2Menu_8           matlab.ui.container.Menu
        Menu_18             matlab.ui.container.Menu
        CH1Menu_9           matlab.ui.container.Menu
        CH2Menu_9           matlab.ui.container.Menu
        Menu_20             matlab.ui.container.Menu
        CH1Menu_10          matlab.ui.container.Menu
        CH2Menu_10          matlab.ui.container.Menu
        Menu_19             matlab.ui.container.Menu
        CH1Menu_11          matlab.ui.container.Menu
        CH2Menu_11          matlab.ui.container.Menu
        Menu_21             matlab.ui.container.Menu
        ysinxMenu           matlab.ui.container.Menu
        Spinner             matlab.ui.control.Spinner
        Label_3             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %其他窗口的句柄
        generate_signal_app
        signal_conv_app
        signal_synthesis_app
        signature_app
        filter_designer_app
        
        %定时器
        Timer_record %用于录制信号
        Timer_genesig %用于产生信号

        %录制信号使用的私有变量
        H
        sampleRate = 44100
        timeLength = 0.05
        samples
        displayTime = 2
        displaySamples=88200
        buffer
        timeArray
        pic
        %产生信号使用的私有变量
        gsBuffer
        gsNewData=0
        gsCount=0
        gsBufferLength=88200
        gschirp
        gsChirpbuffer
        gsChirpCount=0
        gsflag=1
        gspic
        %用于信号合成的私有变量
        ssBuffer
        sspic


        CH1Buffer=0%用于存储CH1所有的录制数据
        CH2Buffer=0%用于存储CH2所有的录制数据
        channel_record%用于记录录制频道
        kk=1%信号放大的增益值
        
    end
    
    properties (Access = public)
        %用于产生信号
        signal_type=1
        channel=1
        T=1
        %用于信号合成
        channell=1
        method=1
        %用于卷积
        channelll=1
        convCore
        %用于信号特征
        snf=0 %频率
        snTT=0 %周期
        snpeak=0 %峰值
        snp2p=0 %峰峰值
        snmean=0 %平均值
        snrmsd=0 %均方差
        snvar=0 %方差
        snrms=0 %rms
        %用于filterdesigner功能
        fdchannel
        fcnname

    end
    
    methods (Access = private)
        
        function capture(app,~,~,temp)%录制信号定时器的回调函数
            % 从音频设备采集数据
            audioIn = step(app.H)*10*app.kk;
            % app.Label_2.Text =sprintf('%.2f',length(audioIn'));

            % 将数据放置在maxbuffer中
            if temp==1
                app.CH1Buffer=[app.CH1Buffer,audioIn'];
            elseif temp==2
                app.CH2Buffer=[app.CH2Buffer,audioIn'];
            end
            % 更新缓冲区：将新数据追加到缓冲区末尾，并移除旧数据
            app.buffer = [app.buffer(app.samples+1:end), audioIn'];
            
            % 更新振幅图
            app.pic.YData = app.buffer;
            
            % 刷新图形
            drawnow limitrate;  
        end

        function setupTimer_record(app,temp)%启动录制信号定时器


            % 如果定时器已存在，先停止并删除它
            if ~isempty(app.Timer_record) && isvalid(app.Timer_record)
                stop(app.Timer_record);
                delete(app.Timer_record);
            end
            
            % 如果音频设备读取对象存在，先释放它
            if isobject(app.H)
                release(app.H);
            end
            
            % 创建音频设备读取对象
            app.H = audioDeviceReader(...
                'DeviceName', '主声音捕获驱动程序', ...
                'NumChannels', 1, ...
                'BitDepth', '16-bit integer', ...
                'SamplesPerFrame', app.samples, ...
                'SampleRate', app.sampleRate);
            % 创建缓冲区
            app.buffer = zeros(1, app.displaySamples);
            %创建绘制对象
            app.timeArray = linspace(-app.displayTime, 0, app.displaySamples);
            if temp==1
                %清除上一次记录
                app.CH1Buffer=0;
                %更改录制频道
                app.channel_record=1;
                %创建图框对象
                app.pic = plot(app.UIAxes, app.timeArray, zeros(1, app.displaySamples));
                set(app.UIAxes, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);
            elseif temp==2
                %清除上一次记录
                app.CH2Buffer=0;
                %更改录制频道
                app.channel_record=2;
                %创建图框对象
                app.pic = plot(app.UIAxes2, app.timeArray, zeros(1, app.displaySamples));
                set(app.UIAxes2, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);
            end
            % 创建定时器对象
            app.Timer_record = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', app.timeLength, ...
                'TimerFcn', @(src, event) capture(app, src, event,temp));
        end
    
        function axes_generate_signal(app,~,~)%产生信号定时器的回调函数
            if app.signal_type==1
                % 生成方波数据
                if app.gsCount<app.T/0.1
                    app.gsCount=app.gsCount+1;
                    if app.gsflag==1
                        app.gsNewData=1*ones(1,2205);
                    else
                        app.gsNewData=0*ones(1,2205);
                    end
                else
                    app.gsCount=0;
                    app.gsflag=~app.gsflag;
                end
            elseif app.signal_type==2
                %生成正弦波
                t=2*pi*0.05/app.T;
                for i=1:2205
                    app.gsNewData(i)=sin(app.gsCount+i*t/2205);
                end
                app.gsCount=app.gsCount+t;
            elseif app.signal_type==4
                %生成chirp信号
                app.gsChirpbuffer=chirp(app.gschirp,1000,300,5000);
                % 每次取出 2205 个数据
                app.Label_2.Text="已经执行";
                % 初始化 gsChirpCount，确保其从1开始
                if app.gsChirpCount == 0
                    app.gsChirpCount = 1;  % 从第一个样本开始
                end

                if (app.gsChirpCount + 2205) <= length(app.gsChirpbuffer)
                    % 从当前计数位置开始取 2205 个数据点
                    app.gsNewData = app.gsChirpbuffer(app.gsChirpCount : app.gsChirpCount + 2205 - 1);
                    app.gsChirpCount = app.gsChirpCount + 2205;  % 每次递增2205个样本点
                else
                    % 如果剩下的样本不足2205个，则重新开始或者补齐
                    app.gsChirpCount = 1;  % 可以选择从头开始
                    app.gsNewData = app.gsChirpbuffer(app.gsChirpCount : app.gsChirpCount + 2205 - 1);
                end
            elseif app.signal_type==3
                %生成阶跃信号
                app.gsNewData=1*ones(1,2205);
            end
            %更新到总buffer中
            if app.channel==1
                app.CH1Buffer=[app.CH1Buffer,app.gsNewData];
            elseif app.channel==2
                app.CH2Buffer=[app.CH2Buffer,app.gsNewData];
            end
            %信号缓存
            app.gsBuffer = [app.gsBuffer(2206:end), app.gsNewData];  
            
            %更新图像
            app.gspic.YData = app.gsBuffer;
            
            % 刷新图形
            drawnow limitrate;  
        end
        
        function setupTimer_genesig(app)%启动产生信号定时器
            if ~isempty(app.Timer_genesig) && isvalid(app.Timer_genesig)
                stop(app.Timer_genesig);
                delete(app.Timer_genesig);
            end
            

            % 初始化缓冲区和时间轴
            app.gsBuffer = zeros(1, app.gsBufferLength);
            app.gschirp = linspace(0, 1, app.gsBufferLength*10);


            %创建绘制对象
            app.timeArray = linspace(-app.displayTime, 0, app.gsBufferLength);
            if app.channel==1
                %清除上一次缓存
                app.CH1Buffer=0;
                %创建图框对象
                app.gspic = plot(app.UIAxes, app.timeArray, zeros(1, app.gsBufferLength));
                set(app.UIAxes, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);
            elseif app.channel==2
                %清除上一次缓存
                app.CH2Buffer=0;
                %创建图框对象
                app.gspic = plot(app.UIAxes2, app.timeArray, zeros(1, app.gsBufferLength));
                set(app.UIAxes2, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);
            end
            % 创建定时器对象
            app.Timer_genesig = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.05, ...
                'TimerFcn', @(src, event) axes_generate_signal(app,src,event));
        end

    end
    
    methods (Access = public)
        
        function start_generate_signal(app)%用于产生信号窗口的回调
            setupTimer_genesig(app);
            start(app.Timer_genesig);
        end
        
        function start_signal_synthesis(app)%用于信号合成窗口的回调
            %先关闭其他两个图像生成
            if ~isempty(app.Timer_genesig) && isvalid(app.Timer_genesig)
                stop(app.Timer_genesig);
                delete(app.Timer_genesig);
            end
            if ~isempty(app.Timer_record) && isvalid(app.Timer_record)
                stop(app.Timer_record);
                delete(app.Timer_record);
            end
            %规定ssBuffer的数组长度
            app.ssBuffer = zeros(1, max(length(app.CH1Buffer), length(app.CH2Buffer)));
            % 取CH1Buffer和CH2Buffer的共同长度
            minLength = min(length(app.CH1Buffer), length(app.CH2Buffer));
            % 对前minLength个元素进行运算
            for i = 1:minLength
                if app.method == 1
                    app.ssBuffer(i) = app.CH1Buffer(i) + app.CH2Buffer(i);
                elseif app.method == 2
                    app.ssBuffer(i) = app.CH1Buffer(i) - app.CH2Buffer(i);
                elseif app.method == 3
                    app.ssBuffer(i) = app.CH2Buffer(i) - app.CH1Buffer(i);
                elseif app.method == 4
                    app.ssBuffer(i) = app.CH1Buffer(i) * app.CH2Buffer(i);
                end
            end
            % 如果CH1Buffer比CH2Buffer长，保留CH1Buffer的剩余部分
            if length(app.CH1Buffer) > minLength
                app.ssBuffer(minLength+1:end) = app.CH1Buffer(minLength+1:end);
            % 如果CH2Buffer比CH1Buffer长，保留CH2Buffer的剩余部分
            elseif length(app.CH2Buffer) > minLength
                app.ssBuffer(minLength+1:end) = app.CH2Buffer(minLength+1:end);
            end
            if app.channell==1
                plot(app.UIAxes, app.ssBuffer);
                title(app.UIAxes, '合成结果');
                axis(app.UIAxes, 'tight');
            elseif app.channell==2
                plot(app.UIAxes2, app.ssBuffer);
                title(app.UIAxes2, '合成结果');
                axis(app.UIAxes2, 'tight');
            end
        end
    
        function start_signal_conv(app)
            % 获取卷积核和输入信号
            if app.channelll==1
                inputSignal = app.CH1Buffer;  % 输入信号
            elseif app.channelll==2
                inputSignal = app.CH2Buffer;  % 输入信号
            end
            convKernel = app.convCore;  % 卷积核
            
            
            % 进行卷积操作
            convResult = conv(inputSignal, convKernel, 'same');  % 'same' 保持输出与输入信号长度相同
            
            % 将卷积结果存储或显示
            if app.channelll==1
                plot(app.UIAxes, convResult);
                title(app.UIAxes, '卷积结果');
                axis(app.UIAxes, 'tight');
            elseif app.channelll==2
                plot(app.UIAxes2, convResult);
                title(app.UIAxes2, '卷积结果');
                axis(app.UIAxes2, 'tight');
            end
            
        end
    
        function start_filter_designer(app)
            % 获取卷积核和输入信号
            if app.fdchannel==1
                inputSignal = app.CH1Buffer;  % 输入信号
            elseif app.fdchannel==2
                inputSignal = app.CH2Buffer;  % 输入信号
            end
            Hd = feval(app.fcnname);  % 动态调用滤波器函数
            filtered_signal = filter(Hd, inputSignal);
            if app.fdchannel==1
                app.CH1Buffer=filtered_signal;  % 输入信号
            elseif app.fdchannel==2
                app.CH2Buffer=filtered_signal;  % 输入信号
            end
            % 将滤波结果显示
            if app.fdchannel==1
                plot(app.UIAxes, app.CH1Buffer);
                title(app.UIAxes, '滤波结果');
                axis(app.UIAxes, 'tight');
            elseif app.fdchannel==2
                plot(app.UIAxes2, app.CH2Buffer);
                title(app.UIAxes2, '滤波结果');
                axis(app.UIAxes2, 'tight');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            app.Menu_5.Enable="off";
            app.generate_signal_app=generate_signal(app);
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            %关闭录制信号
            app.CH1Menu.Enable="on";
            app.CH2Menu.Enable="on";
            if ~isempty(app.Timer_record) && isvalid(app.Timer_record)
                stop(app.Timer_record);
                delete(app.Timer_record);
            end
            if app.channel_record==1
                plot(app.UIAxes, app.CH1Buffer);
                axis(app.UIAxes, 'tight');
            elseif app.channel_record==2
                plot(app.UIAxes2, app.CH2Buffer);
                axis(app.UIAxes2, 'tight');
            end
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            if all(app.CH1Buffer == 0) || all(app.CH2Buffer == 0)
                app.Label_2.Text ="信号合成之前请先确定两个通道都有信号且已暂停";
            else
                app.Menu_8.Enable="off";
                app.signal_synthesis_app=signal_synthesis(app);
            end

        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            if all(app.CH1Buffer == 0) && all(app.CH2Buffer == 0)
                app.Label_2.Text ="滤波之前请先确定目标通道有信号且已暂停";
            else
                app.Menu_11.Enable="off";
                app.signal_conv_app=signal_conv(app);
            end
        end

        % Menu selected function: CH1Menu_4
        function CH1Menu_4Selected(app, event)
            %该回调是自定义函数通道一
            if app.CH1Buffer==0
                app.Label_2.Text="通道一无数据";
            else 

                %计算峰值
                peakvalue2 = app.CH1Buffer(1);
                for i = 2:length(app.CH1Buffer)
                    if app.CH1Buffer(i) > peakvalue2
                        peakvalue2 = app.CH1Buffer(i);
                    end
                end

                %计算方差
                sumvalue2 = 0;
                n = length(app.CH1Buffer);
                for i = 1:n
                    sumvalue2 = sumvalue2 + app.CH1Buffer(i);
                end
                meanvalue2 = sumvalue2 / n;
                squared_diff_sum = 0;
                for i = 1:n
                    squared_diff_sum = squared_diff_sum + (app.CH1Buffer(i) - meanvalue2)^2;
                end
                variancevalue = squared_diff_sum / n;
                
                %计算峰峰值
                maxvalue = app.CH1Buffer(1);
                minvalue = app.CH1Buffer(1);
                for i = 2:length(app.CH1Buffer)
                    if app.CH1Buffer(i) > maxvalue
                        maxvalue = app.CH1Buffer(i);
                    end
                    if app.CH1Buffer(i) < minvalue
                        minvalue = app.CH1Buffer(i);
                    end
                end
                peak2peakvalue2 = maxvalue - minvalue;  
                
                % 计算均方差
                sumvalue1 = 0;
                n = length(app.CH1Buffer);
                for i = 1:n
                    sumvalue1 = sumvalue1 + app.CH1Buffer(i);
                end
                meanvalue = sumvalue1 / n;
                squared_diff_sum = 0;
                for i = 1:n
                    squared_diff_sum = squared_diff_sum + (app.CH1Buffer(i) - meanvalue)^2;
                end
                mean_squared_diff = squared_diff_sum / n;
                rmsdvalue1 = sqrt(mean_squared_diff);

                %计算RMS
                squared_sum = 0;
                n = length(app.CH1Buffer);
                for i = 1:n
                    squared_sum = squared_sum + app.CH1Buffer(i)^2;
                end
                mean_squared = squared_sum / n;
                rmsvalue1 = sqrt(mean_squared);

                % 计算傅里叶变换
                dt=0.05;
                N = length(app.CH1Buffer);
                fft_result = fft(app.CH1Buffer);
                freq = (0:N-1)*(1/(N*dt)); % dt 是采样间隔
                
                % 计算幅度谱
                amplitude = abs(fft_result);
                
                % 找到主频率
                [~, idx] = max(amplitude);
                main_freq = freq(idx);
                
                % 计算周期并传递周期和频率
                if main_freq > 0
                    period = 1 / main_freq;
                    app.snf=main_freq; 
                    app.snTT=period;
                else
                    app.snf=404; 
                    app.snTT=404;
                end
                %传递其他值
                app.snmean=meanvalue2;
                app.snp2p=peak2peakvalue2;
                app.snpeak=peakvalue2;
                app.snrmsd=rmsdvalue1;
                app.snrms=rmsvalue1;
                app.snvar=variancevalue;
                % 在标签中显示最大峰值
                app.CH1Menu_4.Enable="off";
                app.CH1Menu_5.Enable="off";
                app.CH2Menu_4.Enable="off";
                app.CH2Menu_5.Enable="off";
                app.signature_app=signature(app);
            end
        end

        % Menu selected function: CH2Menu_4
        function CH2Menu_4Selected(app, event)
            %该回调是自定义函数通道二
            if app.CH2Buffer==0
                app.Label_2.Text="通道二无数据";
            else 

                %计算峰值
                peakvalue2 = app.CH2Buffer(1);
                for i = 2:length(app.CH2Buffer)
                    if app.CH2Buffer(i) > peakvalue2
                        peakvalue2 = app.CH2Buffer(i);
                    end
                end

                %计算方差
                sumvalue2 = 0;
                n = length(app.CH2Buffer);
                for i = 1:n
                    sumvalue2 = sumvalue2 + app.CH2Buffer(i);
                end
                meanvalue2 = sumvalue2 / n;
                squared_diff_sum = 0;
                for i = 1:n
                    squared_diff_sum = squared_diff_sum + (app.CH2Buffer(i) - meanvalue2)^2;
                end
                variancevalue = squared_diff_sum / n;
                
                %计算峰峰值
                maxvalue = app.CH2Buffer(1);
                minvalue = app.CH2Buffer(1);
                for i = 2:length(app.CH2Buffer)
                    if app.CH2Buffer(i) > maxvalue
                        maxvalue = app.CH2Buffer(i);
                    end
                    if app.CH2Buffer(i) < minvalue
                        minvalue = app.CH2Buffer(i);
                    end
                end
                peak2peakvalue2 = maxvalue - minvalue;  
                
                % 计算均方差
                sumvalue1 = 0;
                n = length(app.CH2Buffer);
                for i = 1:n
                    sumvalue1 = sumvalue1 + app.CH2Buffer(i);
                end
                meanvalue = sumvalue1 / n;
                squared_diff_sum = 0;
                for i = 1:n
                    squared_diff_sum = squared_diff_sum + (app.CH2Buffer(i) - meanvalue)^2;
                end
                mean_squared_diff = squared_diff_sum / n;
                rmsdvalue1 = sqrt(mean_squared_diff);

                %计算RMS
                squared_sum = 0;
                n = length(app.CH2Buffer);
                for i = 1:n
                    squared_sum = squared_sum + app.CH2Buffer(i)^2;
                end
                mean_squared = squared_sum / n;
                rmsvalue1 = sqrt(mean_squared);

                % 计算傅里叶变换
                dt=0.05;
                N = length(app.CH2Buffer);
                fft_result = fft(app.CH2Buffer);
                freq = (0:N-1)*(1/(N*dt)); % dt 是采样间隔
                
                % 计算幅度谱
                amplitude = abs(fft_result);
                
                % 找到主频率
                [~, idx] = max(amplitude);
                main_freq = freq(idx);
                
                % 计算周期并传递周期和频率
                if main_freq > 0
                    period = 1 / main_freq;
                    app.snf=main_freq; 
                    app.snTT=period;
                else
                    app.snf=404; 
                    app.snTT=404;
                end
                %传递其他值
                app.snmean=meanvalue2;
                app.snp2p=peak2peakvalue2;
                app.snpeak=peakvalue2;
                app.snrmsd=rmsdvalue1;
                app.snrms=rmsvalue1;
                app.snvar=variancevalue;
                % 在标签中显示最大峰值
                app.CH1Menu_4.Enable="off";
                app.CH1Menu_5.Enable="off";
                app.CH2Menu_4.Enable="off";
                app.CH2Menu_5.Enable="off";
                app.signature_app=signature(app);
            end
        end

        % Menu selected function: CH1Menu_5
        function CH1Menu_5Selected(app, event)
            %该回调是MATLAB函数通道一
            
            %先检验buffer是否可用
            if app.CH1Buffer==0
                app.Label_2.Text="通道一无数据";
            else
                %计算数值
                peakvalue = max(abs(app.CH1Buffer));
                peak2peakvalue = max(app.CH1Buffer) - min(app.CH1Buffer);               
                rmsvalue = sqrt(mean(app.CH1Buffer.^2));
                signalvar = var(app.CH1Buffer);
                signalmean = mean(app.CH1Buffer);
                rmsdvalue = sqrt(mean((app.CH1Buffer - signalmean).^2));
               
                % 计算傅里叶变换
                dt=0.05;
                N = length(app.CH1Buffer);
                fft_result = fft(app.CH1Buffer);
                freq = (0:N-1)*(1/(N*dt)); % dt 是采样间隔
                
                % 计算幅度谱
                amplitude = abs(fft_result);
                
                % 找到主频率
                [~, idx] = max(amplitude);
                main_freq = freq(idx);
                
                % 计算周期并传递周期和频率
                if main_freq > 0
                    period = 1 / main_freq;
                    app.snf=main_freq; 
                    app.snTT=period;
                else
                    app.snf=404; 
                    app.snTT=404;
                end
                %传递其他值
                app.snmean=signalmean;
                app.snp2p=peak2peakvalue;
                app.snpeak=peakvalue;
                app.snrmsd=rmsdvalue;
                app.snrms=rmsvalue;
                app.snvar=signalvar;
    
                %关闭打开按钮
                app.CH1Menu_4.Enable="off";
                app.CH1Menu_5.Enable="off";
                app.CH2Menu_4.Enable="off";
                app.CH2Menu_5.Enable="off";
                app.signature_app=signature(app);       
            end            
        end

        % Menu selected function: CH2Menu_5
        function CH2Menu_5Selected(app, event)
            %该回调是MATLAB函数通道二
            
            %先检验buffer是否可用
            if app.CH2Buffer==0
                app.Label_2.Text="通道二无数据";
            else
                %计算数值
                peakvalue = max(abs(app.CH2Buffer));
                peak2peakvalue = max(app.CH2Buffer) - min(app.CH2Buffer);               
                rmsvalue = sqrt(mean(app.CH2Buffer.^2));
                signalvar = var(app.CH2Buffer);
                signalmean = mean(app.CH2Buffer);
                rmsdvalue = sqrt(mean((app.CH2Buffer - signalmean).^2));
                
                % 计算傅里叶变换
                dt=0.05;
                N = length(app.CH2Buffer);
                fft_result = fft(app.CH2Buffer);
                freq = (0:N-1)*(1/(N*dt)); % dt 是采样间隔
                
                % 计算幅度谱
                amplitude = abs(fft_result);
                
                % 找到主频率
                [~, idx] = max(amplitude);
                main_freq = freq(idx);
                
                % 计算周期并传递周期和频率
                if main_freq > 0
                    period = 1 / main_freq;
                    app.snf=main_freq; 
                    app.snTT=period;
                else
                    app.snf=404; 
                    app.snTT=404;
                end
                %传递其他值
                app.snmean=signalmean;
                app.snp2p=peak2peakvalue;
                app.snpeak=peakvalue;
                app.snrmsd=rmsdvalue;
                app.snrms=rmsvalue;
                app.snvar=signalvar;
    
                %关闭打开按钮
                app.CH1Menu_4.Enable="off";
                app.CH1Menu_5.Enable="off";
                app.CH2Menu_4.Enable="off";
                app.CH2Menu_5.Enable="off";
                app.signature_app=signature(app);       
            end
        end

        % Menu selected function: CH1Menu
        function CH1MenuSelected(app, event)
            % 初始化定时器参数
            app.samples = floor(app.timeLength * app.sampleRate);
            
            % 设置定时器
            setupTimer_record(app,1);
            
            % 启动定时器
            start(app.Timer_record);
            %关闭按钮
            app.CH1Menu.Enable="off";
            app.CH2Menu.Enable="off";
        end

        % Menu selected function: CH2Menu
        function CH2MenuSelected(app, event)
            % 初始化定时器参数
            app.samples = floor(app.timeLength * app.sampleRate);
            
            
            % 设置定时器
            setupTimer_record(app,2);
            
            % 启动定时器
            start(app.Timer_record);
            %关闭按钮
            app.CH1Menu.Enable="off";
            app.CH2Menu.Enable="off";
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            %信号增益调整
            changingValue = event.Value;
            app.kk=changingValue;
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            %关闭产生信号计时器
            if ~isempty(app.Timer_genesig) && isvalid(app.Timer_genesig)
                stop(app.Timer_genesig);
                delete(app.Timer_genesig);
            end
            if app.channel==1
                plot(app.UIAxes, app.CH1Buffer);
                axis(app.UIAxes, 'tight');
            elseif app.channel==2
                plot(app.UIAxes2, app.CH2Buffer);
                axis(app.UIAxes2, 'tight');
            end
            
        end

        % Menu selected function: CH1Menu_7
        function CH1Menu_7Selected(app, event)
            %CH1的功率谱分析
            if isempty(app.CH1Buffer) || all(app.CH1Buffer == 0)
                app.Label_2.Text = "CH1中无信号";
                return;
            end
            % 设置参数
            Fs = 44100;  % 采样频率，根据你的实际情况设置
            window = hamming(1024);  % 窗口函数
            noverlap = 512;  % 窗口重叠
            nfft = 1024;  % FFT 点数
        
            % 计算功率谱密度
            [Pxx, f] = pwelch(app.CH1Buffer, window, noverlap, nfft, Fs);
        
            % 清空当前Axes并绘制功率谱
            cla(app.UIAxes);  % 清空Axes
            plot(app.UIAxes, f, 10*log10(Pxx));  % 绘制功率谱（转换为dB）
            
            % 设置坐标轴属性
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Power/Frequency (dB/Hz)');
            title(app.UIAxes, 'Power Spectrum of CH1Buffer');
            axis(app.UIAxes, 'tight');
                
        end

        % Menu selected function: CH1Menu_8
        function CH1Menu_8Selected(app, event)
            %CH1的幅值谱分析
            if isempty(app.CH1Buffer) || all(app.CH1Buffer == 0)
                app.Label_2.Text = "CH1中无信号";
                return;
            end
            
            % 设置参数
            Fs = 44100;  % 采样频率，根据你的实际情况设置
            L = length(app.CH1Buffer);  % 信号长度
            
            % 计算 FFT
            Y = fft(app.CH1Buffer);  % 计算信号的 FFT
            P2 = abs(Y/L);  % 双边幅值谱
            P1 = P2(1:L/2+1);  % 单边幅值谱
            P1(2:end-1) = 2*P1(2:end-1);  % 去除冗余的幅值
            
            % 计算频率轴
            f = Fs*(0:(L/2))/L;  % 频率范围
            
            % 清空当前Axes并绘制幅值谱
            cla(app.UIAxes);  % 清空Axes
            plot(app.UIAxes, f, P1);  % 绘制幅值谱
            
            % 设置坐标轴属性
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Magnitude');
            title(app.UIAxes, 'Magnitude Spectrum of CH1Buffer');
            axis(app.UIAxes, 'tight');  % 紧凑坐标轴
        end

        % Menu selected function: CH1Menu_9
        function CH1Menu_9Selected(app, event)
            %CH1的相位谱分析
            if isempty(app.CH1Buffer) || all(app.CH1Buffer == 0)
                app.Label_2.Text = "CH1中无信号";
                return;
            end
            
            % 设置参数
            Fs = 44100;  % 采样频率，根据你的实际情况设置
            L = length(app.CH1Buffer);  % 信号长度
            
            % 计算 FFT
            Y = fft(app.CH1Buffer);  % 计算信号的 FFT
            
            % 计算相位谱
            phase = angle(Y);  % 提取相位信息
            
            % 计算频率轴
            f = Fs*(0:(L/2))/L;  % 频率范围
            
            % 清空当前Axes并绘制相位谱
            cla(app.UIAxes);  % 清空Axes
            plot(app.UIAxes, f, phase(1:L/2+1));  % 绘制相位谱
            
            % 设置坐标轴属性
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Phase (radians)');
            title(app.UIAxes, 'Phase Spectrum of CH1Buffer');
            axis(app.UIAxes, 'tight');  % 紧凑坐标轴
        end

        % Menu selected function: CH1Menu_10
        function CH1Menu_10Selected(app, event)
            %CH1的实频谱分析
            if isempty(app.CH1Buffer) || all(app.CH1Buffer == 0)
                app.Label_2.Text = "CH1中无信号";
                return;
            end
            
            % 设置参数
            Fs = 44100;  % 采样频率，根据你的实际情况设置
            L = length(app.CH1Buffer);  % 信号长度
            
            % 计算 FFT
            Y = fft(app.CH1Buffer);  % 计算信号的 FFT
            
            % 计算实频谱（幅值谱）
            magnitude = abs(Y);  % 取 FFT 结果的绝对值作为幅值谱
            
            % 计算频率轴
            f = Fs*(0:(L/2))/L;  % 频率范围
            
            % 清空当前Axes并绘制实频谱
            cla(app.UIAxes);  % 清空Axes
            plot(app.UIAxes, f, magnitude(1:L/2+1));  % 绘制实频谱
            
            % 设置坐标轴属性
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Magnitude');
            title(app.UIAxes, 'Magnitude Spectrum of CH1Buffer');
            axis(app.UIAxes, 'tight');  % 紧凑坐标轴
        end

        % Menu selected function: CH1Menu_11
        function CH1Menu_11Selected(app, event)
            %CH1的虚频谱分析
            if isempty(app.CH1Buffer) || all(app.CH1Buffer == 0)
                app.Label_2.Text = "CH1中无信号";
                return;
            end
            
            % 设置参数
            Fs = 44100;  % 采样频率，根据你的实际情况设置
            L = length(app.CH1Buffer);  % 信号长度
            
            % 计算 FFT
            Y = fft(app.CH1Buffer);  % 计算信号的 FFT
            
            % 计算虚频谱（虚部谱）
            imaginary = imag(Y);  % 取 FFT 结果的虚部作为虚频谱
            
            % 计算频率轴
            f = Fs * (0:(L/2)) / L;  % 频率范围
            
            % 清空当前Axes并绘制虚频谱
            cla(app.UIAxes);  % 清空Axes
            plot(app.UIAxes, f, imaginary(1:L/2+1));  % 绘制虚频谱
            
            % 设置坐标轴属性
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Imaginary Part');
            title(app.UIAxes, 'Imaginary Spectrum of CH1Buffer');
            axis(app.UIAxes, 'tight');  % 紧凑坐标轴
        end

        % Menu selected function: CH2Menu_7
        function CH2Menu_7Selected(app, event)
            %CH2的功率谱分析
            if isempty(app.CH2Buffer) || all(app.CH2Buffer == 0)
                app.Label_2.Text = "CH2中无信号";
                return;
            end
            % 设置参数
            Fs = 44100;  % 采样频率，根据你的实际情况设置
            window = hamming(1024);  % 窗口函数
            noverlap = 512;  % 窗口重叠
            nfft = 1024;  % FFT 点数
        
            % 计算功率谱密度
            [Pxx, f] = pwelch(app.CH2Buffer, window, noverlap, nfft, Fs);
        
            % 清空当前Axes并绘制功率谱
            cla(app.UIAxes2);  % 清空Axes
            plot(app.UIAxes2, f, 10*log10(Pxx));  % 绘制功率谱（转换为dB）
            
            % 设置坐标轴属性
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Power/Frequency (dB/Hz)');
            title(app.UIAxes2, 'Power Spectrum of CH1Buffer');
            axis(app.UIAxes2, 'tight');
        end

        % Menu selected function: CH2Menu_8
        function CH2Menu_8Selected(app, event)
            %CH2的幅值谱分析
            if isempty(app.CH2Buffer) || all(app.CH2Buffer == 0)
                app.Label_2.Text = "CH2中无信号";
                return;
            end
            
            % 设置参数
            Fs = 44100;  % 采样频率，根据你的实际情况设置
            L = length(app.CH2Buffer);  % 信号长度
            
            % 计算 FFT
            Y = fft(app.CH2Buffer);  % 计算信号的 FFT
            P2 = abs(Y/L);  % 双边幅值谱
            P1 = P2(1:L/2+1);  % 单边幅值谱
            P1(2:end-1) = 2*P1(2:end-1);  % 去除冗余的幅值
            
            % 计算频率轴
            f = Fs*(0:(L/2))/L;  % 频率范围
            
            % 清空当前Axes并绘制幅值谱
            cla(app.UIAxes2);  % 清空Axes
            plot(app.UIAxes2, f, P1);  % 绘制幅值谱
            
            % 设置坐标轴属性
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Magnitude');
            title(app.UIAxes2, 'Magnitude Spectrum of CH1Buffer');
            axis(app.UIAxes2, 'tight');  % 紧凑坐标轴
        end

        % Menu selected function: CH2Menu_9
        function CH2Menu_9Selected(app, event)
            %CH2的相位谱分析
            if isempty(app.CH2Buffer) || all(app.CH2Buffer == 0)
                app.Label_2.Text = "CH2中无信号";
                return;
            end
            
            % 设置参数
            Fs = 44100;  % 采样频率，根据你的实际情况设置
            L = length(app.CH2Buffer);  % 信号长度
            
            % 计算 FFT
            Y = fft(app.CH2Buffer);  % 计算信号的 FFT
            
            % 计算相位谱
            phase = angle(Y);  % 提取相位信息
            
            % 计算频率轴
            f = Fs*(0:(L/2))/L;  % 频率范围
            
            % 清空当前Axes并绘制相位谱
            cla(app.UIAxes2);  % 清空Axes
            plot(app.UIAxes2, f, phase(1:L/2+1));  % 绘制相位谱
            
            % 设置坐标轴属性
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Phase (radians)');
            title(app.UIAxes2, 'Phase Spectrum of CH1Buffer');
            axis(app.UIAxes2, 'tight');  % 紧凑坐标轴
        end

        % Menu selected function: CH2Menu_10
        function CH2Menu_10Selected(app, event)
            %CH2的实频谱分析
            if isempty(app.CH2Buffer) || all(app.CH2Buffer == 0)
                app.Label_2.Text = "CH2中无信号";
                return;
            end
            
            % 设置参数
            Fs = 44100;  % 采样频率，根据你的实际情况设置
            L = length(app.CH2Buffer);  % 信号长度
            
            % 计算 FFT
            Y = fft(app.CH2Buffer);  % 计算信号的 FFT
            
            % 计算实频谱（幅值谱）
            magnitude = abs(Y);  % 取 FFT 结果的绝对值作为幅值谱
            
            % 计算频率轴
            f = Fs*(0:(L/2))/L;  % 频率范围
            
            % 清空当前Axes并绘制实频谱
            cla(app.UIAxes2);  % 清空Axes
            plot(app.UIAxes2, f, magnitude(1:L/2+1));  % 绘制实频谱
            
            % 设置坐标轴属性
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Magnitude');
            title(app.UIAxes2, 'Magnitude Spectrum of CH1Buffer');
            axis(app.UIAxes2, 'tight');  % 紧凑坐标轴
        end

        % Menu selected function: CH2Menu_11
        function CH2Menu_11Selected(app, event)
            %CH2的虚频谱分析
            if isempty(app.CH2Buffer) || all(app.CH2Buffer == 0)
                app.Label_2.Text = "CH2中无信号";
                return;
            end
            
            % 设置参数
            Fs = 44100;  % 采样频率，根据你的实际情况设置
            L = length(app.CH2Buffer);  % 信号长度
            
            % 计算 FFT
            Y = fft(app.CH2Buffer);  % 计算信号的 FFT
            
            % 计算虚频谱（虚部谱）
            imaginary = imag(Y);  % 取 FFT 结果的虚部作为虚频谱
            
            % 计算频率轴
            f = Fs * (0:(L/2)) / L;  % 频率范围
            
            % 清空当前Axes并绘制虚频谱
            cla(app.UIAxes2);  % 清空Axes
            plot(app.UIAxes2, f, imaginary(1:L/2+1));  % 绘制虚频谱
            
            % 设置坐标轴属性
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Imaginary Part');
            title(app.UIAxes2, 'Imaginary Spectrum of CH1Buffer');
            axis(app.UIAxes2, 'tight');  % 紧凑坐标轴
        end

        % Menu selected function: ysinxMenu
        function ysinxMenuSelected(app, event)
            % 定义时间参数
            T_FFT = 1; % 信号周期
            Fs = 1000; % 采样频率
            t = 0:1/Fs:T_FFT; % 时间向量
        
            % 生成占空比0.5的方波信号
            signal = square(2 * pi * (1/T_FFT) * t, 50); % 50% 占空比
        
            % 清空绘图区域
            cla(app.UIAxes); 
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
        
            % 画出原始方波信号
            plot(app.UIAxes, t, signal, 'k', 'DisplayName', 'Square Wave');
            legend(app.UIAxes, 'show');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Fourier Series Approximation of a Square Wave');
        
            % 逐步累加傅里叶级数项
            N = 20; % 设置近似的阶数
            approximation = zeros(size(t)); % 初始化逼近信号
            plotHandles = gobjects(0); % 初始化曲线句柄数组
        
            for n = 1:N
                % 计算当前的傅里叶级数项
                term = (4/(pi*(2*n-1))) * sin(2*pi*(2*n-1)*t/T_FFT);
                approximation = approximation + term; % 累加
                
                % 动态更新图形
                h = plot(app.UIAxes, t, approximation, 'r', 'DisplayName', sprintf('Approximation with %d Terms', n));
                plotHandles = [plotHandles; h]; % 保存句柄
        
                % 保留最新的五条曲线
                if numel(plotHandles) > 5
                    delete(plotHandles(1)); % 删除最旧的曲线
                    plotHandles(1) = []; % 更新句柄数组
                end
                
                legend(app.UIAxes, 'show');
                pause(0.5); % 暂停以展示动态效果
            end
        end

        % Menu selected function: convCH1CH2Menu
        function convCH1CH2MenuSelected(app, event)
            %该回调用于卷积conv(CH1,CH2)
            if all(app.CH1Buffer == 0) || all(app.CH2Buffer == 0)
                app.Label_2.Text ="卷积之前请先确定两个通道都有信号且已暂停";
            else
                Buffer_temp=conv(app.CH1Buffer,app.CH2Buffer,'full');
                %注意这里修改了原buffer的值
                app.CH1Buffer=Buffer_temp;
                plot(app.UIAxes, app.CH1Buffer);
                axis(app.UIAxes, 'tight');
            end
        end

        % Menu selected function: convCH2CH1Menu
        function convCH2CH1MenuSelected(app, event)
            %该回调用于卷积conv(CH2,CH1)
            if all(app.CH1Buffer == 0) || all(app.CH2Buffer == 0)
                app.Label_2.Text ="卷积之前请先确定两个通道都有信号且已暂停";
            else
                Buffer_temp=conv(app.CH2Buffer,app.CH1Buffer,'full');
                %注意这里修改了原buffer的值
                app.CH2Buffer=Buffer_temp;
                plot(app.UIAxes2, app.CH2Buffer);
                axis(app.UIAxes2, 'tight');
            end
        end

        % Menu selected function: CH1Menu_14
        function CH1Menu_14Selected(app, event)
            %导出CH1信号
            if app.CH1Buffer==0
                app.Label_2.Text="CH1无信号";
            else
                % 弹出文件保存对话框，用户选择保存路径和文件名
                [file, path] = uiputfile('*.txt', 'Save Signal As');
                
                % 如果用户没有取消保存
                if ischar(file)
                    % 拼接完整的文件路径
                    fullFileName = fullfile(path, file);
                    
                    % 保存 CH1Buffer 数组到文件
                    writematrix(app.CH1Buffer, fullFileName);
                    
                    % 提示保存成功
                    app.Label_2.Text="保存成功";
                else
                    % 用户取消保存操作
                    app.Label_2.Text="保存失败";
                end
            end
        end

        % Menu selected function: CH2Menu_14
        function CH2Menu_14Selected(app, event)
            %导出CH2信号
            if app.CH2Buffer==0
                app.Label_2.Text="CH2无信号";
            else
                % 弹出文件保存对话框，用户选择保存路径和文件名
                [file, path] = uiputfile('*.txt', 'Save Signal As');
                
                % 如果用户没有取消保存
                if ischar(file)
                    % 拼接完整的文件路径
                    fullFileName = fullfile(path, file);
                    
                    % 保存 CH1Buffer 数组到文件
                    writematrix(app.CH2Buffer, fullFileName);
                    
                    % 提示保存成功
                    app.Label_2.Text="保存成功";
                else
                    % 用户取消保存操作
                    app.Label_2.Text="保存失败";
                end
            end
        end

        % Menu selected function: CH1Menu_15
        function CH1Menu_15Selected(app, event)
            %导入信号至CH1
            % 弹出文件选择对话框，用户选择要加载的文件
            [file, path] = uigetfile('*.txt', 'Select Signal File');
            
            % 如果用户选择了文件
            if ischar(file)
                % 拼接完整的文件路径
                fullFileName = fullfile(path, file);
                
                % 读取文件中的数据并存储到 CH2Buffer
                app.CH1Buffer = readmatrix(fullFileName);
                
                % 提示读取成功
                app.Label_2.Text="读取成功";
            else
                % 用户取消读取操作
                app.Label_2.Text="读取失败";
            end
            plot(app.UIAxes, app.CH1Buffer);
            axis(app.UIAxes, 'tight');
        end

        % Menu selected function: CH2Menu_15
        function CH2Menu_15Selected(app, event)
            %导入信号至CH2
            % 弹出文件选择对话框，用户选择要加载的文件
            [file, path] = uigetfile('*.txt', 'Select Signal File');
            
            % 如果用户选择了文件
            if ischar(file)
                % 拼接完整的文件路径
                fullFileName = fullfile(path, file);
                
                % 读取文件中的数据并存储到 CH2Buffer
                app.CH2Buffer = readmatrix(fullFileName);
                
                % 提示读取成功
                app.Label_2.Text="读取成功";
            else
                % 用户取消读取操作
                app.Label_2.Text="读取失败";
            end
            plot(app.UIAxes2, app.CH2Buffer);
            axis(app.UIAxes2, 'tight');
        end

        % Menu selected function: CH1Menu_12
        function CH1Menu_12Selected(app, event)
            %CH1自相关计算
            if app.CH1Buffer==0
                app.Label_2.Text ="进行CH1自相关计算之前请先确定通道有信号且已暂停";
            else
                Buffer_temp = xcorr(app.CH1Buffer);
                %注意这里修改了原buffer的值
                app.CH1Buffer=Buffer_temp;
                plot(app.UIAxes, app.CH1Buffer);
                axis(app.UIAxes, 'tight');
            end
        end

        % Menu selected function: CH2Menu_12
        function CH2Menu_12Selected(app, event)
            %CH2自相关计算
            if app.CH2Buffer==0
                app.Label_2.Text ="进行CH2自相关计算之前请先确定通道有信号且已暂停";
            else
                Buffer_temp = xcorr(app.CH2Buffer);
                %注意这里修改了原buffer的值
                app.CH2Buffer=Buffer_temp;
                plot(app.UIAxes2, app.CH2Buffer);
                axis(app.UIAxes2, 'tight');
            end
        end

        % Menu selected function: xcorrCH1CH2Menu
        function xcorrCH1CH2MenuSelected(app, event)
            %xcorr(CH1, CH2)互相关计算
            if all(app.CH1Buffer == 0) || all(app.CH2Buffer == 0)
                app.Label_2.Text ="进行互相关计算之前请先确定两个通道都有信号且已暂停";
            else
                Buffer_temp=xcorr(app.CH1Buffer, app.CH2Buffer);
                %注意这里修改了原buffer的值
                app.CH1Buffer=Buffer_temp;
                plot(app.UIAxes, app.CH1Buffer);
                axis(app.UIAxes, 'tight');
            end
        end

        % Menu selected function: xcorrCH2CH1Menu
        function xcorrCH2CH1MenuSelected(app, event)
            %xcorr(CH2, CH1)互相关计算
            if all(app.CH1Buffer == 0) || all(app.CH2Buffer == 0)
                app.Label_2.Text ="进行互相关计算之前请先确定两个通道都有信号且已暂停";
            else
                Buffer_temp=xcorr(app.CH2Buffer, app.CH1Buffer);
                %注意这里修改了原buffer的值
                app.CH2Buffer=Buffer_temp;
                plot(app.UIAxes2, app.CH2Buffer);
                axis(app.UIAxes2, 'tight');
            end
        end

        % Menu selected function: FilterDesignerMenu
        function FilterDesignerMenuSelected(app, event)
            if all(app.CH1Buffer == 0) && all(app.CH2Buffer == 0)
                app.Label_2.Text ="滤波之前请先确定目标通道有信号且已暂停";
            else
                app.FilterDesignerMenu.Enable="off";
                app.filter_designer_app=filter_designer(app);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '信号';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.Text = '开始录制信号';
            app.CH1Menu = uimenu(app.Menu_3);
            app.CH1Menu.MenuSelectedFcn = createCallbackFcn(app, @CH1MenuSelected, true);
            app.CH1Menu.Text = 'CH1';
            app.CH2Menu = uimenu(app.Menu_3);
            app.CH2Menu.MenuSelectedFcn = createCallbackFcn(app, @CH2MenuSelected, true);
            app.CH2Menu.Text = 'CH2';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '暂停录制信号';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '信号发生器';
            app.Menu_6 = uimenu(app.Menu);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '暂停发生信号';
            app.Menu_25 = uimenu(app.Menu);
            app.Menu_25.Text = '导出信号';
            app.CH1Menu_14 = uimenu(app.Menu_25);
            app.CH1Menu_14.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_14Selected, true);
            app.CH1Menu_14.Text = 'CH1';
            app.CH2Menu_14 = uimenu(app.Menu_25);
            app.CH2Menu_14.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_14Selected, true);
            app.CH2Menu_14.Text = 'CH2';
            app.Menu_26 = uimenu(app.Menu);
            app.Menu_26.Text = '导入信号';
            app.CH1Menu_15 = uimenu(app.Menu_26);
            app.CH1Menu_15.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_15Selected, true);
            app.CH1Menu_15.Text = 'CH1';
            app.CH2Menu_15 = uimenu(app.Menu_26);
            app.CH2Menu_15.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_15Selected, true);
            app.CH2Menu_15.Text = 'CH2';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '波形';
            app.Menu_7 = uimenu(app.Menu_2);
            app.Menu_7.Text = '波形特征';
            app.Menu_9 = uimenu(app.Menu_7);
            app.Menu_9.Text = '自定义函数';
            app.CH1Menu_4 = uimenu(app.Menu_9);
            app.CH1Menu_4.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_4Selected, true);
            app.CH1Menu_4.Text = 'CH1';
            app.CH2Menu_4 = uimenu(app.Menu_9);
            app.CH2Menu_4.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_4Selected, true);
            app.CH2Menu_4.Text = 'CH2';
            app.MATLABMenu = uimenu(app.Menu_7);
            app.MATLABMenu.Text = 'MATLAB函数';
            app.CH1Menu_5 = uimenu(app.MATLABMenu);
            app.CH1Menu_5.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_5Selected, true);
            app.CH1Menu_5.Text = 'CH1';
            app.CH2Menu_5 = uimenu(app.MATLABMenu);
            app.CH2Menu_5.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_5Selected, true);
            app.CH2Menu_5.Text = 'CH2';
            app.Menu_8 = uimenu(app.Menu_2);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '信号合成';
            app.Menu_12 = uimenu(app.UIFigure);
            app.Menu_12.Text = '高级计算';
            app.Menu_13 = uimenu(app.Menu_12);
            app.Menu_13.Text = '卷积';
            app.convCH1CH2Menu = uimenu(app.Menu_13);
            app.convCH1CH2Menu.MenuSelectedFcn = createCallbackFcn(app, @convCH1CH2MenuSelected, true);
            app.convCH1CH2Menu.Text = 'conv(CH1,CH2)';
            app.convCH2CH1Menu = uimenu(app.Menu_13);
            app.convCH2CH1Menu.MenuSelectedFcn = createCallbackFcn(app, @convCH2CH1MenuSelected, true);
            app.convCH2CH1Menu.Text = 'conv(CH2,CH1)';
            app.Menu_14 = uimenu(app.Menu_12);
            app.Menu_14.Text = '相关';
            app.Menu_22 = uimenu(app.Menu_14);
            app.Menu_22.Text = '自相关';
            app.CH1Menu_12 = uimenu(app.Menu_22);
            app.CH1Menu_12.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_12Selected, true);
            app.CH1Menu_12.Text = 'CH1';
            app.CH2Menu_12 = uimenu(app.Menu_22);
            app.CH2Menu_12.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_12Selected, true);
            app.CH2Menu_12.Text = 'CH2';
            app.Menu_23 = uimenu(app.Menu_14);
            app.Menu_23.Text = '互相关';
            app.xcorrCH1CH2Menu = uimenu(app.Menu_23);
            app.xcorrCH1CH2Menu.MenuSelectedFcn = createCallbackFcn(app, @xcorrCH1CH2MenuSelected, true);
            app.xcorrCH1CH2Menu.Text = 'xcorr(CH1, CH2)';
            app.xcorrCH2CH1Menu = uimenu(app.Menu_23);
            app.xcorrCH2CH1Menu.MenuSelectedFcn = createCallbackFcn(app, @xcorrCH2CH1MenuSelected, true);
            app.xcorrCH2CH1Menu.Text = 'xcorr(CH2, CH1)';
            app.Menu_10 = uimenu(app.UIFigure);
            app.Menu_10.Text = '滤波';
            app.Menu_11 = uimenu(app.Menu_10);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '简单一维卷积核';
            app.FilterDesignerMenu = uimenu(app.Menu_10);
            app.FilterDesignerMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterDesignerMenuSelected, true);
            app.FilterDesignerMenu.Text = 'FilterDesigner';
            app.Menu_15 = uimenu(app.UIFigure);
            app.Menu_15.Text = '生成谱';
            app.Menu_16 = uimenu(app.Menu_15);
            app.Menu_16.Text = '功率谱';
            app.CH1Menu_7 = uimenu(app.Menu_16);
            app.CH1Menu_7.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_7Selected, true);
            app.CH1Menu_7.Text = 'CH1';
            app.CH2Menu_7 = uimenu(app.Menu_16);
            app.CH2Menu_7.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_7Selected, true);
            app.CH2Menu_7.Text = 'CH2';
            app.Menu_17 = uimenu(app.Menu_15);
            app.Menu_17.Text = '幅值谱';
            app.CH1Menu_8 = uimenu(app.Menu_17);
            app.CH1Menu_8.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_8Selected, true);
            app.CH1Menu_8.Text = 'CH1';
            app.CH2Menu_8 = uimenu(app.Menu_17);
            app.CH2Menu_8.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_8Selected, true);
            app.CH2Menu_8.Text = 'CH2';
            app.Menu_18 = uimenu(app.Menu_15);
            app.Menu_18.Text = '相位谱';
            app.CH1Menu_9 = uimenu(app.Menu_18);
            app.CH1Menu_9.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_9Selected, true);
            app.CH1Menu_9.Text = 'CH1';
            app.CH2Menu_9 = uimenu(app.Menu_18);
            app.CH2Menu_9.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_9Selected, true);
            app.CH2Menu_9.Text = 'CH2';
            app.Menu_20 = uimenu(app.Menu_15);
            app.Menu_20.Text = '实频谱';
            app.CH1Menu_10 = uimenu(app.Menu_20);
            app.CH1Menu_10.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_10Selected, true);
            app.CH1Menu_10.Text = 'CH1';
            app.CH2Menu_10 = uimenu(app.Menu_20);
            app.CH2Menu_10.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_10Selected, true);
            app.CH2Menu_10.Text = 'CH2';
            app.Menu_19 = uimenu(app.Menu_15);
            app.Menu_19.Text = '虚频谱';
            app.CH1Menu_11 = uimenu(app.Menu_19);
            app.CH1Menu_11.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_11Selected, true);
            app.CH1Menu_11.Text = 'CH1';
            app.CH2Menu_11 = uimenu(app.Menu_19);
            app.CH2Menu_11.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_11Selected, true);
            app.CH2Menu_11.Text = 'CH2';
            app.Menu_21 = uimenu(app.UIFigure);
            app.Menu_21.Text = '三角信号求和逼近';
            app.ysinxMenu = uimenu(app.Menu_21);
            app.ysinxMenu.MenuSelectedFcn = createCallbackFcn(app, @ysinxMenuSelected, true);
            app.ysinxMenu.Text = 'y=sin(x)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CH1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CH2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '提示：';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '信号振幅放大';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end