classdef signal_conv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        x1x2x3Label      matlab.ui.control.Label
        NEditField       matlab.ui.control.EditField
        NEditFieldLabel  matlab.ui.control.Label
        Button           matlab.ui.control.Button
        ButtonGroup      matlab.ui.container.ButtonGroup
        CH2Button        matlab.ui.control.RadioButton
        CH1Button        matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        CallingApp % Description
        convCore
        channelll
        workflag=0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.CallingApp=main;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.workflag==1
                %将值赋给临时变量
                if app.CH1Button.Value==1
                    app.channelll=1;
                elseif app.CH2Button==1
                    app.channelll=2;
                end
                %将值传递给主程序变量
                app.CallingApp.convCore=app.convCore;
                app.CallingApp.channelll=app.channelll;
                %打开按钮
                app.CallingApp.Menu_11.Enable="on";
                %启动回调卷积函数
                app.CallingApp.start_signal_conv();
                %删除此页面
                delete(app);
            elseif app.workflag==0
                app.Label.Text="请按照标准格式输入后点击确认";
            end
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;  % 获取文本框输入

            % 定义正则表达式：匹配一个或多个浮点数（包括正负数），之间用英文逗号分隔
            pattern = '^-?\d+(\.\d+)?(,-?\d+(\.\d+)?)*$';
            
            % 使用正则表达式进行验证
            if ~isempty(regexp(value, pattern, 'once'))
                % 输入有效，分割并转换为浮点数数组
                numericValues = str2double(strsplit(value, ','));  % 按逗号分割，并转换为数字
                
                % 将有效的数字存储到 convCore 中
                app.convCore = numericValues;  % 假设你已经声明了convCore为全局或类属性变量
                
                app.Label.Text="有效，点击确认关闭窗口";

                app.workflag=1;
            else
                % 输入无效，处理错误情况
                app.convCore = [];  % 输入无效时清空convCore或进行相应处理
                app.Label.Text="无效，请按照标准格式输入";
                app.workflag=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '卷积对象';
            app.CH1Button = uiradiobutton(app.ButtonGroup);
            app.CH1Button.Text = 'CH1';
            app.CH1Button.Value = true;
            app.CH2Button = uiradiobutton(app.ButtonGroup);
            app.CH2Button.Text = 'CH2';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = '卷积核N';
            app.NEditField = uieditfield(app.UIFigure, 'text');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.x1x2x3Label = uilabel(app.UIFigure);
            app.x1x2x3Label.Text = '请以  x1,x2,x3 形式输入卷积核';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_conv(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end