classdef cfy20224392 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        Menu_3       matlab.ui.container.Menu
        Menu_7       matlab.ui.container.Menu
        chirpMenu    matlab.ui.container.Menu
        Menu_8       matlab.ui.container.Menu
        Menu_2       matlab.ui.container.Menu
        Menu_4       matlab.ui.container.Menu
        Menu_5       matlab.ui.container.Menu
        Menu_6       matlab.ui.container.Menu
        Menu_20      matlab.ui.container.Menu
        Menu_9       matlab.ui.container.Menu
        Menu_12      matlab.ui.container.Menu
        Menu_11      matlab.ui.container.Menu
        Menu_10      matlab.ui.container.Menu
        Menu_13      matlab.ui.container.Menu
        Menu_14      matlab.ui.container.Menu
        Menu_15      matlab.ui.container.Menu
        Menu_16      matlab.ui.container.Menu
        Menu_17      matlab.ui.container.Menu
        Menu_18      matlab.ui.container.Menu
        RMSMenu      matlab.ui.container.Menu
        Menu_19      matlab.ui.container.Menu
        Menu_21      matlab.ui.container.Menu
        Label_5      matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        MATLABLabel  matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Spinner      matlab.ui.control.Spinner
        Label        matlab.ui.control.Label
        Button       matlab.ui.control.Button
        UIAxes3      matlab.ui.control.UIAxes
        UIAxes1      matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
    end

    %此处声明私有属性
    properties (Access = private)
        %用于捕捉麦克风
        Timer_2
        H
        sampleRate = 44100
        timeLength = 0.05
        samples
        displayTime = 2
        displaySamples
        buffer
        timeArray
        pic
        %用于生成信号
        Timer_3
        signalbuffer
        t
        count=0
        flag=1
        newDataPoint=0
        signal_flag
        ychirp
        chripcount=0
        %用于处理运算
        Timer_1
        process_flag
        process_buffer
        process_newdata=0
        k
        %用于信号分析
        j=1
        %用于处理是否可以进行分析性质的算法
        maxbuffer=0
        analysis_flag=0
        isconv=0
        conv_result
    end

    %此处声明私有函数
    methods (Access = private)
        %此处处理定时器Timer_2，用于捕捉麦克风信号
        function capture(app,~,~)
            % 从音频设备采集数据
            audioIn = step(app.H)*10*app.j;
            % 将数据放置在maxbuffer中
            app.maxbuffer=[app.maxbuffer,audioIn'];
            % 更新缓冲区：将新数据追加到缓冲区末尾，并移除旧数据
            app.buffer = [app.buffer(app.samples+1:end), audioIn'];
            
            % 更新振幅图
            app.pic.YData = app.buffer;
            
            % 刷新图形
            drawnow limitrate;  
        end
        function setupTimer_2(app)


            % 如果定时器已存在，先停止并删除它
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            
            % 如果音频设备读取对象存在，先释放它
            if isobject(app.H)
                release(app.H);
            end
            
            % 创建音频设备读取对象
            app.H = audioDeviceReader(...
                'DeviceName', '主声音捕获驱动程序', ...
                'NumChannels', 1, ...
                'BitDepth', '16-bit integer', ...
                'SamplesPerFrame', app.samples, ...
                'SampleRate', app.sampleRate);
            %将分析标志置为0
            app.analysis_flag=0;
            % 创建缓冲区
            app.buffer = zeros(1, app.displaySamples);
            app.timeArray = linspace(-app.displayTime, 0, app.displaySamples);

            % 在UIAxes2上创建图形
            app.pic = plot(app.UIAxes2, app.timeArray, zeros(1, app.displaySamples));
            set(app.UIAxes2, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);

            % 创建定时器对象
            app.Timer_2 = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', app.timeLength, ...
                'TimerFcn', @(src, event) capture(app, src, event));
        end
        %此处处理定时器Timer_3，用于生成信号
        function signal_generate(app)
            if app.signal_flag==0
                % 生成方波数据
                if app.count<10
                    app.count=app.count+1;
                else
                    if app.flag==1
                        app.flag=0;
                        app.newDataPoint=0.5;
                    else
                        app.flag=1;
                        app.newDataPoint=0;
                    end
                    app.count=0;
                end
            elseif app.signal_flag==1
                %生成正弦波
                app.count=app.count+0.05;
                app.newDataPoint=sin(app.count);
            elseif app.signal_flag==2
                %生成chirp信号
                app.ychirp=chirp(app.t,1000,300,2000);
                app.chripcount=app.chripcount+1;
                app.newDataPoint=app.ychirp(app.chripcount);
            elseif app.signal_flag==3
                %生成阶跃信号
                app.newDataPoint=0.8;
                if app.count<10
                    app.newDataPoint=0;
                    app.count=app.count+1;
                end
            end   
            % 更新图像数据
            app.signalbuffer = [app.signalbuffer(2:end), app.newDataPoint];  
            plot(app.UIAxes1,app.t, app.signalbuffer);
            xlim(app.UIAxes1, [0 1]);  % 设置 X 轴范围
            ylim(app.UIAxes1, [-1 1]);  % 设置 Y 轴范围
        end
        function setupTimer_3(app)
            % 如果定时器已存在，先停止并删除它
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            bufferLength = 300;  % 缓冲区长度
            if isempty(app.signalbuffer)
                % 初始化缓冲区和时间轴
                
                app.signalbuffer = zeros(1, bufferLength);
                app.t = linspace(0, 1, bufferLength);
            end
            app.newDataPoint = 0; 
            % 创建定时器对象
            app.Timer_3 = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.05, ...
                'TimerFcn', @(src, event) signal_generate(app));
        end
        %此处处理定时器Timer_1，用于处理运算
        function process(app)
            if app.process_flag==0    
                app.process_newdata=app.buffer(end)+app.signalbuffer(end);
            elseif app.process_flag==1
                app.process_newdata=app.buffer(end)-app.signalbuffer(end);
            elseif app.process_flag==2
                app.process_newdata=app.buffer(end)*app.signalbuffer(end);
            end
            app.process_buffer = [app.process_buffer(2:end), app.process_newdata];  
            plot(app.UIAxes3,app.t, app.process_buffer);
            xlim(app.UIAxes3, [0 1]);  % 设置 X 轴范围
            ylim(app.UIAxes3, [-1 1]);  % 设置 Y 轴范围
        end
        function setupTimer_1(app)
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
            if isempty(app.process_buffer)
                % 初始化缓冲区和时间轴
                app.process_buffer = zeros(1, 300);
                app.k = linspace(0, 1, 300);
            end 
            % 创建定时器对象
            app.Timer_1 = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.05, ...
                'TimerFcn', @(src, event) process(app));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function CloseButton_Fcn(app, event)
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
            % 释放音频设备
            if isobject(app.H)
                release(app.H);
            end
            
            % 关闭应用程序窗口
            close(app.UIFigure);
        end

        % Callback function
        function PulseButton_Fcn(app, event)
            %将分析标志置位1
            app.analysis_flag=1;

            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
        end

        % Callback function
        function StartButton_Fcn(app, event)
            % 初始化定时器参数
            app.samples = floor(app.timeLength * app.sampleRate);
            app.displaySamples = app.displayTime * app.sampleRate;
            
            % 设置定时器
            setupTimer_2(app);
            
            % 启动定时器
            start(app.Timer_2);
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            app.signal_flag=0;
            setupTimer_3(app);
            start(app.Timer_3);
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            app.signal_flag=1;
            setupTimer_3(app);
            start(app.Timer_3);
        end

        % Menu selected function: chirpMenu
        function chirpMenuSelected(app, event)
            app.signal_flag=2;
            setupTimer_3(app);
            start(app.Timer_3);
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            app.signal_flag=3;
            setupTimer_3(app);
            start(app.Timer_3);
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            app.process_flag=0;
            setupTimer_1(app);
            start(app.Timer_1);
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            app.process_flag=1;
            setupTimer_1(app);
            start(app.Timer_1);
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            app.process_flag=2;
            setupTimer_1(app);
            start(app.Timer_1);
        end

        % Menu selected function: Menu_12
        function Menu_12Selected(app, event)
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
            % 释放音频设备
            if isobject(app.H)
                release(app.H);
            end
            
            % 关闭应用程序窗口
            close(app.UIFigure);
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            app.analysis_flag=1;
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            % 初始化定时器参数
            app.samples = floor(app.timeLength * app.sampleRate);
            app.displaySamples = app.displayTime * app.sampleRate;
            
            % 设置定时器
            setupTimer_2(app);
            
            % 启动定时器
            start(app.Timer_2);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
            % 释放音频设备
            if isobject(app.H)
                release(app.H);
            end
            
            % 关闭应用程序窗口
            close(app.UIFigure);
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.j=changingValue;
        end

        % Menu selected function: Menu_13
        function Menu_13Selected(app, event)

        end

        % Menu selected function: Menu_14
        function Menu_14Selected(app, event)
            if app.analysis_flag==0
                app.Label_5.Text="请先暂停采集";
            else
                dt=0.05;
                % 计算傅里叶变换
                N = length(app.maxbuffer);
                fft_result = fft(app.maxbuffer);
                freq = (0:N-1)*(1/(N*dt)); % dt 是采样间隔
                
                % 计算幅度谱
                amplitude = abs(fft_result);
                
                % 找到主频率
                [~, idx] = max(amplitude);
                main_freq = freq(idx);
                
                % 计算周期
                if main_freq > 0
                    period = 1 / main_freq;
                    app.Label_2.Text = sprintf('主要频率为：%.4f Hz\n估计周期为：%.4f', main_freq, period);
                else
                    app.Label_10.Text = '信号没有明显周期性。';
                end
                % 只取前半部分的频谱数据（对称）
                half_N = floor(N/2) + 1;
                freq = freq(1:half_N);
                amplitude = amplitude(1:half_N);
                
                % 绘制频谱图
                plot(app.UIAxes2, freq, amplitude);
                title(app.UIAxes2, '频谱图');
                xlabel(app.UIAxes2, '频率 (Hz)');
                ylabel(app.UIAxes2, '幅度');
                axis(app.UIAxes2, 'tight');
                app.Label_3.Text = sprintf(' ');
            end
            
        end

        % Menu selected function: Menu_17
        function Menu_17Selected(app, event)
            if app.analysis_flag==0
                app.Label_5.Text="请先暂停采集";
            else
                signalmean = mean(app.maxbuffer); % 使用 mean 函数计算均值
                app.Label_2.Text = sprintf('均值为：%.8f', signalmean); % 使用 sprintf 格式化字符串
                % 初始化总和为0
                sumvalue = 0;
                
                % 获取数组的长度
                n = length(app.maxbuffer);
                
                % 遍历数组计算总和
                for i = 1:n
                    sumvalue = sumvalue + app.maxbuffer(i);
                end
                
                % 计算均值
                meanvalue = sumvalue / n;
                
                % 在标签中显示均值
                app.Label_3.Text = sprintf('均值为：%.8f', meanvalue);
            end
        end

        % Menu selected function: Menu_19
        function Menu_19Selected(app, event)
            if app.analysis_flag==0
                app.Label_5.Text="请先暂停采集";
            else
                signalvar = var(app.maxbuffer);
                app.Label_2.Text = sprintf('方差为：%.8f', signalvar);


                % 计算均值
                sumvalue2 = 0;
                n = length(app.maxbuffer);
                for i = 1:n
                    sumvalue2 = sumvalue2 + app.maxbuffer(i);
                end
                meanvalue2 = sumvalue2 / n;
                
                % 初始化平方差总和为0
                squared_diff_sum = 0;
                
                % 计算每个元素与均值的平方差
                for i = 1:n
                    squared_diff_sum = squared_diff_sum + (app.maxbuffer(i) - meanvalue2)^2;
                end
                
                % 计算方差
                variancevalue = squared_diff_sum / n;
                
                % 在标签中显示方差
                app.Label_3.Text = sprintf('方差为：%.8f', variancevalue);
            end

        end

        % Menu selected function: Menu_15
        function Menu_15Selected(app, event)
            if app.analysis_flag==0
                app.Label_5.Text="请先暂停采集";
            else
                peakvalue = max(abs(app.maxbuffer));
                app.Label_2.Text = sprintf('峰值为：%.8f', peakvalue);

                % 初始化最大值为数组中的第一个元素
                peakvalue2 = app.maxbuffer(1);
                
                % 遍历数组找出最大值
                for i = 2:length(app.maxbuffer)
                    if app.maxbuffer(i) > peakvalue2
                        peakvalue2 = app.maxbuffer(i);
                    end
                end
                
                % 在标签中显示最大峰值
                app.Label_3.Text = sprintf('峰值为：%.8f', peakvalue2);
            end
            
        end

        % Menu selected function: Menu_16
        function Menu_16Selected(app, event)
            if app.analysis_flag==0
                app.Label_5.Text="请先暂停采集";
            else
                peak2peakvalue = max(app.maxbuffer) - min(app.maxbuffer);
                app.Label_2.Text = sprintf('峰峰值为：%.8f', peak2peakvalue);

                % 初始化最大值为数组中的第一个元素
                maxvalue = app.maxbuffer(1);
                
                % 初始化最小值为数组中的第一个元素
                minvalue = app.maxbuffer(1);
                
                % 遍历数组找出最大值和最小值
                for i = 2:length(app.maxbuffer)
                    if app.maxbuffer(i) > maxvalue
                        maxvalue = app.maxbuffer(i);
                    end
                    if app.maxbuffer(i) < minvalue
                        minvalue = app.maxbuffer(i);
                    end
                end
                
                % 计算峰峰值
                peak2peakvalue2 = maxvalue - minvalue;
                
                % 在标签中显示峰峰值
                app.Label_3.Text = sprintf('峰峰值为：%.8f', peak2peakvalue2);
            end
        end

        % Menu selected function: Menu_18
        function Menu_18Selected(app, event)
            if app.analysis_flag==0
                app.Label_5.Text="请先暂停采集";
            else
                signalmean = mean(app.maxbuffer);
                rmsdvalue = sqrt(mean((app.maxbuffer - signalmean).^2));
                app.Label_2.Text = sprintf('均方差为：%.8f', rmsdvalue);


                % 计算均值
                sumvalue1 = 0;
                n = length(app.maxbuffer);
                for i = 1:n
                    sumvalue1 = sumvalue1 + app.maxbuffer(i);
                end
                meanvalue = sumvalue1 / n;
                
                % 初始化平方差总和为0
                squared_diff_sum = 0;
                
                % 计算每个元素与均值的平方差
                for i = 1:n
                    squared_diff_sum = squared_diff_sum + (app.maxbuffer(i) - meanvalue)^2;
                end
                
                % 计算均方差（标准差）
                mean_squared_diff = squared_diff_sum / n;
                rmsdvalue1 = sqrt(mean_squared_diff);
                
                % 在标签中显示均方差
                app.Label_3.Text = sprintf('均方差为：%.8f', rmsdvalue1);
            end
        end

        % Menu selected function: RMSMenu
        function RMSMenuSelected(app, event)
            if app.analysis_flag==0
                app.Label_5.Text="请先暂停采集";
            else
                rmsvalue = sqrt(mean(app.maxbuffer.^2));
                app.Label_2.Text = sprintf('RMS为：%.8f', rmsvalue);


                % 初始化平方和为0
                squared_sum = 0;
                
                % 获取数组的长度
                n = length(app.maxbuffer);
                
                % 遍历数组，计算每个元素的平方，并求和
                for i = 1:n
                    squared_sum = squared_sum + app.maxbuffer(i)^2;
                end
                
                % 计算平方的平均值
                mean_squared = squared_sum / n;
                
                % 计算RMS
                rmsvalue1 = sqrt(mean_squared);
                
                % 在标签中显示RMS
                app.Label_3.Text = sprintf('RMS为：%.8f', rmsvalue1);
            end
        end

        % Menu selected function: Menu_20
        function Menu_20Selected(app, event)
            if app.analysis_flag==0
                app.Label_5.Text="请先暂停采集";
            else
                app.isconv=1;
                % 定义信号和滤波器
                signal = app.maxbuffer; % 输入信号
                filter = [0.5, 0.5]; % 示例滤波器
                
                % 初始化卷积结果
                app.conv_result = zeros(1, length(signal) + length(filter) - 1);
                
                % 计算卷积
                for i = 1:length(app.conv_result)
                    for q = 1:length(filter)
                        if i-q+1 > 0 && i-q+1 <= length(signal)
                            app.conv_result(i) = app.conv_result(i) + signal(i-q+1) * filter(q);
                        end
                    end
                end
                
                % 绘制卷积结果
                plot(app.UIAxes2, app.conv_result);
                title(app.UIAxes2, '卷积结果');
                xlabel(app.UIAxes2, '样本');
                ylabel(app.UIAxes2, '幅度');
                axis(app.UIAxes2, 'tight');
            end 
        end

        % Menu selected function: Menu_21
        function Menu_21Selected(app, event)
            if app.isconv==0
                app.Label_5.Text="请先进行卷积操作";
            else
                peakvalue = max(abs(app.conv_result));
                peak2peakvalue = max(app.conv_result) - min(app.conv_result);               
                rmsvalue = sqrt(mean(app.conv_result.^2));
                signalvar = var(app.conv_result);
                signalmean = mean(app.conv_result);
                rmsdvalue = sqrt(mean((app.conv_result - signalmean).^2));
                app.Label_5.Text = sprintf('峰值为：%.8f 峰峰值为：%.8f\nRMS为：%.8f 方差为：%.8f\n均值为：%.8f 均方差为：%.8f', peakvalue,peak2peakvalue,rmsvalue,signalvar,signalmean,rmsdvalue);


            end
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '产生波形';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '方波';
            app.Menu_7 = uimenu(app.Menu);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '正弦波';
            app.chirpMenu = uimenu(app.Menu);
            app.chirpMenu.MenuSelectedFcn = createCallbackFcn(app, @chirpMenuSelected, true);
            app.chirpMenu.Text = 'chirp';
            app.Menu_8 = uimenu(app.Menu);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '阶跃信号';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '波形运算';
            app.Menu_4 = uimenu(app.Menu_2);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '+';
            app.Menu_5 = uimenu(app.Menu_2);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '-';
            app.Menu_6 = uimenu(app.Menu_2);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '*';
            app.Menu_20 = uimenu(app.Menu_2);
            app.Menu_20.MenuSelectedFcn = createCallbackFcn(app, @Menu_20Selected, true);
            app.Menu_20.Text = '卷积';
            app.Menu_9 = uimenu(app.UIFigure);
            app.Menu_9.Text = '麦克风录制';
            app.Menu_12 = uimenu(app.Menu_9);
            app.Menu_12.MenuSelectedFcn = createCallbackFcn(app, @Menu_12Selected, true);
            app.Menu_12.Text = '关闭';
            app.Menu_11 = uimenu(app.Menu_9);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '暂停';
            app.Menu_10 = uimenu(app.Menu_9);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '开始';
            app.Menu_13 = uimenu(app.UIFigure);
            app.Menu_13.MenuSelectedFcn = createCallbackFcn(app, @Menu_13Selected, true);
            app.Menu_13.Text = '分析信号';
            app.Menu_14 = uimenu(app.Menu_13);
            app.Menu_14.MenuSelectedFcn = createCallbackFcn(app, @Menu_14Selected, true);
            app.Menu_14.Text = '周期';
            app.Menu_15 = uimenu(app.Menu_13);
            app.Menu_15.MenuSelectedFcn = createCallbackFcn(app, @Menu_15Selected, true);
            app.Menu_15.Text = '峰值';
            app.Menu_16 = uimenu(app.Menu_13);
            app.Menu_16.MenuSelectedFcn = createCallbackFcn(app, @Menu_16Selected, true);
            app.Menu_16.Text = '峰峰值';
            app.Menu_17 = uimenu(app.Menu_13);
            app.Menu_17.MenuSelectedFcn = createCallbackFcn(app, @Menu_17Selected, true);
            app.Menu_17.Text = '均值';
            app.Menu_18 = uimenu(app.Menu_13);
            app.Menu_18.MenuSelectedFcn = createCallbackFcn(app, @Menu_18Selected, true);
            app.Menu_18.Text = '均方差';
            app.RMSMenu = uimenu(app.Menu_13);
            app.RMSMenu.MenuSelectedFcn = createCallbackFcn(app, @RMSMenuSelected, true);
            app.RMSMenu.Text = 'RMS';
            app.Menu_19 = uimenu(app.Menu_13);
            app.Menu_19.MenuSelectedFcn = createCallbackFcn(app, @Menu_19Selected, true);
            app.Menu_19.Text = '方差';
            app.Menu_21 = uimenu(app.Menu_13);
            app.Menu_21.MenuSelectedFcn = createCallbackFcn(app, @Menu_21Selected, true);
            app.Menu_21.Text = '分析卷积信号';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '麦克风录制')
            xlabel(app.UIAxes2, '时间')
            ylabel(app.UIAxes2, '振幅')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '产生波形')
            xlabel(app.UIAxes1, '时间')
            ylabel(app.UIAxes1, '振幅')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '波形运算')
            xlabel(app.UIAxes3, '时间')
            ylabel(app.UIAxes3, '振幅')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '关闭';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '麦克风信号缩放';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Limits = [0 100];
            app.Spinner.Value = 10;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.MATLABLabel = uilabel(app.UIFigure);
            app.MATLABLabel.Text = 'MATLAB函数：';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '自定义函数：';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cfy20224392

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end